classdef test6_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UpdateButton  matlab.ui.control.Button
        DropDown      matlab.ui.control.DropDown
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Get the selected function from DropDown
    selectedFunction = app.DropDown.Value;

    % Define x range for the plot
    x = linspace(-2*pi, 2*pi, 100); % From -2π to 2π

    % Depending on the selected function, plot the appropriate graph
    switch selectedFunction
        case 'Sin(x)'
            y = sin(x);
        case 'Cos(x)'
            y = cos(x);
        case 'x'
            y = x;
        case 'arcTan(x)'
            y = atan(x); % Note: arctan values should be between -1 and 1
    end

    % Plot on UIAxes
    plot(app.UIAxes, x, y);
    title(app.UIAxes, ['Graph of ', selectedFunction]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图片')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '函数名';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sin(x)', 'Cos(x)', 'x', 'arcTan(x)'};
            app.DropDown.Value = 'Sin(x)';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test6_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end