classdef test6_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Gauge        matlab.ui.control.NinetyDegreeGauge
        Label_2      matlab.ui.control.Label
        ClickButton  matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        Label        matlab.ui.control.Label
    end

    
    properties (Access = public)
        ClickCount = 0 % Initialize click count
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClickButton
        function ClickButtonPushed(app, event)
             % Increment the click count
            app.ClickCount = app.ClickCount + 1

            % Update the TextArea with the new count
            app.TextArea.Value = num2str(app.ClickCount)

            % Update the Gauge to reflect the click frequency or count
            app.Gauge.Value = app.ClickCount % This can be adjusted as needed

            if(app.ClickCount == 100)
                app.ClickCount = 0
                app.TextArea.Value = "你真无聊！"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '按键次数';
            app.TextArea = uitextarea(app.UIFigure);
            app.ClickButton = uibutton(app.UIFigure, 'push');
            app.ClickButton.ButtonPushedFcn = createCallbackFcn(app, @ClickButtonPushed, true);
            app.ClickButton.Text = 'Click';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '按键频率';
            app.Gauge = uigauge(app.UIFigure, 'ninetydegree');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test6_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end