classdef test6_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label_2      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        EditField    matlab.ui.control.EditField
        SubButton    matlab.ui.control.Button
        PlusButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubButton
        function SubButtonPushed(app, event)
            % 假设 app.EditField 是一个数值类型（例如 uitextfield）
% 如果要在 Label_2 中显示字符串
app.Label_2.Text = "-";

% 如果要从 EditField 和 EditField_2 中得到数值并计算差异
value1 = str2double(app.EditField.Value);
value2 = str2double(app.EditField_2.Value);

% 确保计算后的结果是数值，然后将其转换为字符串进行显示
result = value1 - value2;

% 如果 EditField_3 是用于显示结果的文本框
app.EditField_3.Value = num2str(result);

        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
              % 假设 app.EditField 是一个数值类型（例如 uitextfield）
% 如果要在 Label_2 中显示字符串
app.Label_2.Text = "+";

% 如果要从 EditField 和 EditField_2 中得到数值并计算差异
value1 = str2double(app.EditField.Value);
value2 = str2double(app.EditField_2.Value);

% 确保计算后的结果是数值，然后将其转换为字符串进行显示
result = value1 + value2;

% 如果 EditField_3 是用于显示结果的文本框
app.EditField_3.Value = num2str(result);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlusButton = uibutton(app.UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = 'Plus';
            app.SubButton = uibutton(app.UIFigure, 'push');
            app.SubButton.ButtonPushedFcn = createCallbackFcn(app, @SubButtonPushed, true);
            app.SubButton.Text = 'Sub';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '±';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test6_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end