classdef test6_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_4     matlab.ui.control.RadioButton
        Image        matlab.ui.control.Image
        Button_3     matlab.ui.control.RadioButton
        Button_2     matlab.ui.control.RadioButton
        Button       matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
           
              % 获取选择的单选按钮
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            
            % 根据选择的按钮改变 Image 的背景颜色
            switch selectedButton
                case '红'
                    % 将背景颜色设置为红色
                    app.Image.BackgroundColor = [1 0 0]; 
                    
                case '绿'
                    % 将背景颜色设置为绿色
                    app.Image.BackgroundColor = [0 1 0];
                    
                case '蓝'
                    % 将背景颜色设置为蓝色
                    app.Image.BackgroundColor = [0 0 1];
                    
                otherwise
                    % 默认颜色（黄色）
                    app.Image.BackgroundColor = [1 1 0];
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
           
              % 获取选择的单选按钮
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            
            % 根据选择的按钮改变 Image 的背景颜色
            switch selectedButton
                case '红'
                    % 将背景颜色设置为红色
                    app.Image.BackgroundColor = [1 0 0]; 
                    app.Image.ImageSource=""
                case '绿'
                    % 将背景颜色设置为绿色
                    app.Image.BackgroundColor = [0 1 0];
                    app.Image.ImageSource=""
                case '蓝'
                    % 将背景颜色设置为蓝色
                    app.Image.BackgroundColor = [0 0 1];
                    app.Image.ImageSource=""
                case '图片'
                    app.Image.BackgroundColor = [0 0 0];
                    app.Image.ImageSource="./1.jpg"
                otherwise
                    % 默认颜色（黄色）
                    app.Image.BackgroundColor = [1 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '颜色';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '红';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '绿';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '蓝';
            app.Image = uiimage(app.ButtonGroup);
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '图片';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test6_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end